classdef userInterface3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        PlayVideoButton               matlab.ui.control.Button
        SpecifyAreaButton             matlab.ui.control.Button
        CrossoversCounter             matlab.ui.control.Label
        TotalcrossoversLabel          matlab.ui.control.Label
        RemoveOneButton               matlab.ui.control.Button
        AddOneButton                  matlab.ui.control.Button
        NOButton                      matlab.ui.control.Button
        YESButton                     matlab.ui.control.Button
        CrossoverLabel                matlab.ui.control.Label
        UpdateThresholdButton         matlab.ui.control.Button
        PixelAreaThresholdEditField   matlab.ui.control.NumericEditField
        PixelAreaThresholddefault2EditFieldLabel  matlab.ui.control.Label
        BrightnessThresholdEditField  matlab.ui.control.NumericEditField
        BrightnessThresholddefault50EditFieldLabel  matlab.ui.control.Label
        LOADButton                    matlab.ui.control.Button
        ImagefilenameEditField        matlab.ui.control.EditField
        ProjectionFilenameEditFieldLabel  matlab.ui.control.Label
        StackNameEditField            matlab.ui.control.EditField
        Stackfilenametif12imagelimitLabel  matlab.ui.control.Label
        Depth12                       matlab.ui.control.UIAxes
        Depth11                       matlab.ui.control.UIAxes
        Depth10                       matlab.ui.control.UIAxes
        Depth9                        matlab.ui.control.UIAxes
        Depth8                        matlab.ui.control.UIAxes
        Depth7                        matlab.ui.control.UIAxes
        Depth6                        matlab.ui.control.UIAxes
        Depth5                        matlab.ui.control.UIAxes
        Depth4                        matlab.ui.control.UIAxes
        Depth3                        matlab.ui.control.UIAxes
        Depth2                        matlab.ui.control.UIAxes
        Depth1                        matlab.ui.control.UIAxes
        SpecificViewOriginal          matlab.ui.control.UIAxes
        SpecificViewColored           matlab.ui.control.UIAxes
        FullViewImage                 matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % update main plot: replot stars
        function updateFullView(app)
            global coloredLabelsImage blobMeasurements
            hold(app.FullViewImage, "off")
            imshow(coloredLabelsImage, 'Parent', app.FullViewImage)
            app.FullViewImage.PlotBoxAspectRatio = [1 1 1];
            hold(app.FullViewImage, "on")
            i = 1;
            while i <= length(blobMeasurements)
                xy = blobMeasurements(i).Centroid;
                plot(app.FullViewImage,xy(1),xy(2),"w*",MarkerSize=10)
                i = i + 1;
            end
        end

        % draw bounding box on main plot to show current blob
        function drawbox(app)
            global index blobMeasurements
            box = blobMeasurements(index).BoundingBox;
            if box(3) > box(4)
                L = box(3);
            else
                L = box(4);
            end
            rectangle(app.FullViewImage, 'Position', [box(1), box(2), L, L], 'EdgeColor',"magenta",'LineWidth',2)
        end
        
        % refresh total crossovers counter
        function refreshCounter(app)
            global numberOfBlobs blobMeasurements extras
            if size(blobMeasurements, 2) ~= 0
                numberOfBlobs = size(blobMeasurements, 1);
            else
                numberOfBlobs = 0;
            end
            app.CrossoversCounter.Text = string(numberOfBlobs + extras);
        end
        
        % test if all crossovers have been accounted for
        function out = testForBounds(app)
            global index numberOfBlobs extras blobMeasurements
            if index > numberOfBlobs
                msgbox("All crossovers accounted for. Final crossover count: " + string(numberOfBlobs + extras));
                out = true;
                coords = extractfield(blobMeasurements,'Centroid');
                output(:,1) = coords(1:2:end);
                output(:,2) = coords(2:2:end);
                if exist('output.xlsx', 'file')==2
                    delete('output.xlsx');
                end
                writematrix(output,'output.xlsx')
            else
                out = false;
            end
        end
        
        % update colored region plot (left)
        function updateColored(app)
            global coloredLabelsImage
            [xbounds, ybounds] = xybounds(app,15);
            tempImage = coloredLabelsImage(xbounds, ybounds, :);
            imshow(tempImage,'Parent', app.SpecificViewColored)
        end
        
        % update gray projection region plot (right)
        function updateOriginal(app)
            global I
            [xbounds, ybounds] = xybounds(app,15);
            tempImage = I(xbounds, ybounds, :);
            imshow(tempImage,'Parent', app.SpecificViewOriginal)
        end
        
        % update plots showing 12 depth levels
        function updateDepth(app,x,y)
            global index blobMeasurements stackI
            box = blobMeasurements(index).BoundingBox;

            % set bounds for box
            extra = 15;
            if box(2) - extra >= 0 && box(2) + extra <= 1024
                xbound = box(2) - extra + y(1): box(2) - extra + y(2);
            else
                xbound = box(2) + y(1) : box(2) + y(2);
            end
            if box(1) - extra >= 0 && box(1) + extra <= 1024
                ybound = box(1) - extra + x(1) : box(1) - extra + x(2);
            else
                ybound = box(1) + x(1) : box(1) + x(2);
            end
            xbound = round(xbound);
            ybound = round(ybound);
            depth = size(stackI,4);
            % yes ik this is cringe but idk how to get the handle to work
            for i = 1:depth
                if i == 1
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth1)
                elseif i == 2
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth2)
                elseif i == 3
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth3)
                elseif i == 4
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth4)
                elseif i == 5
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth5)
                elseif i == 6
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth6)
                elseif i == 7
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth7)
                elseif i == 8
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth8)
                elseif i == 9
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth9)
                elseif i == 10
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth10)
                elseif i == 11
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth11)
                elseif i == 12
                    imshow(stackI(xbound,ybound,:,i),'Parent',app.Depth12)
                end
            end
        end
        
        % play video of region over all depths on projection region plot
        % (right)
        function playVideo(app)
            global stackI
            [xbounds, ybounds] = xybounds(app,15);
            temp = size(stackI);
            depth = temp(4);
            for i = 1:depth
                imshow(stackI(xbounds,ybounds,:,i),'Parent',app.SpecificViewOriginal)
                pause(0.20)
            end
            for i = flip(1:depth)
                imshow(stackI(xbounds,ybounds,:,i),'Parent',app.SpecificViewOriginal)
                pause(0.20)
            end
        end
        
        % find bounds for plotting region plots with extra pixels "extra"
        function [xbounds, ybounds] = xybounds(app, extra)
            global index blobMeasurements
            box = blobMeasurements(index).BoundingBox;
            if box(3) > box(4)
                L = box(3);
            else
                L = box(4);
            end
            
            if box(2) - extra >= 0 && box(2) + L + extra <= 1024
                xbounds = box(2) - extra: box(2) + L + extra;
            else
                xbounds = box(2) : box(2) + L;
            end
            if box(1) - extra >= 0 && box(1) + L + extra <= 1024
                ybounds = box(1) - extra: box(1) + L + extra;
            else
                ybounds = box(1) : box(1) + L;
            end
            xbounds = round(xbounds);
            ybounds = round(ybounds);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LOADButton
        function LOADButtonPushed(app, event)
            % declare variables
            global index blobMeasurements numberOfBlobs coloredLabelsImage I grayImage extras stackI
            index = 1;
            extras = 0;

            % load threshold values
            brightnessThreshold = app.BrightnessThresholdEditField.Value;
            if brightnessThreshold == 0
                brightnessThreshold = 50;
            end
            areaThreshold = app.PixelAreaThresholdEditField.Value;
            if areaThreshold == 0
                areaThreshold = 2;
            end

            % load stack images
            imageName = app.StackNameEditField.Value;
            stackI = tiffread(imageName);
            if size(stackI,4) > 12
                msgbox("Please limit stack file to 12 or less images.");
            end

            % load projection image
            imageName = app.ImagefilenameEditField.Value;
            I = imread(imageName);
            grayImage = im2gray(I);
            % thresholding
            binaryImage = grayImage(:,:,1) < brightnessThreshold;
            binaryImage = ~binaryImage;
            binaryImage = bwareaopen(binaryImage,4,4);
            binaryImage = ~binaryImage;
            
            % Label each blob with 4-connectivity
            % (https://www.mathworks.com/matlabcentral/answers/530843-detection-identification-of-a-curve-in-an-image)
            [labeledImage, numberOfBlobs] = bwlabel(binaryImage, 4);
            % Apply a variety of pseudo-colors to the regions
            coloredLabelsImage = label2rgb (labeledImage, 'hsv', 'k', 'shuffle');
            imshow(coloredLabelsImage, 'Parent', app.FullViewImage)
            app.FullViewImage.PlotBoxAspectRatio = [1 1 1];
            hold(app.FullViewImage, "on")
            % get property of each blob
            blobMeasurements = regionprops(labeledImage, 'all');

            % thresholding & plot white star at each blob
            i = 1;
            while i <= size(blobMeasurements,1)
                if blobMeasurements(i).Area > 100000 || blobMeasurements(i).Area < areaThreshold
                    blobMeasurements(i) = [];
                else
                    xy = blobMeasurements(i).Centroid;
                    plot(app.FullViewImage,xy(1),xy(2),"w*",MarkerSize=10)
                    i = i + 1;
                end
            end
            refreshCounter(app)

            if testForBounds(app) == false
                drawbox(app)
                updateColored(app)
                updateOriginal(app)
            end
        end

        % Button pushed function: UpdateThresholdButton
        function UpdateThresholdButtonPushed(app, event)
            % declare variables
            global index blobMeasurements numberOfBlobs coloredLabelsImage I grayImage extras stackI
            index = 1;
            extras = 0;

            % load threshold values
            brightnessThreshold = app.BrightnessThresholdEditField.Value;
            if brightnessThreshold == 0
                brightnessThreshold = 50;
            end
            areaThreshold = app.PixelAreaThresholdEditField.Value;
            if areaThreshold == 0
                areaThreshold = 2;
            end

            % load stack images
            imageName = app.StackNameEditField.Value;
            stackI = tiffread(imageName);
            if size(stackI,4) > 12
                msgbox("Please limit stack file to 12 or less images.");
            end

            % load projection image
            imageName = app.ImagefilenameEditField.Value;
            I = imread(imageName);
            grayImage = im2gray(I);
            % thresholding
            binaryImage = grayImage(:,:,1) < brightnessThreshold;
            binaryImage = ~binaryImage;
            binaryImage = bwareaopen(binaryImage,4,4);
            binaryImage = ~binaryImage;
            
            % Label each blob with 4-connectivity
            % (https://www.mathworks.com/matlabcentral/answers/530843-detection-identification-of-a-curve-in-an-image)
            [labeledImage, numberOfBlobs] = bwlabel(binaryImage, 4);
            % Apply a variety of pseudo-colors to the regions
            coloredLabelsImage = label2rgb (labeledImage, 'hsv', 'k', 'shuffle');
            imshow(coloredLabelsImage, 'Parent', app.FullViewImage)
            app.FullViewImage.PlotBoxAspectRatio = [1 1 1];
            hold(app.FullViewImage, "on")
            % get property of each blob
            blobMeasurements = regionprops(labeledImage, 'all');

            % thresholding & plot white star at each blob
            i = 1;
            while i <= size(blobMeasurements,1)
                if blobMeasurements(i).Area > 100000 || blobMeasurements(i).Area < areaThreshold
                    blobMeasurements(i) = [];
                else
                    xy = blobMeasurements(i).Centroid;
                    plot(app.FullViewImage,xy(1),xy(2),"w*",MarkerSize=10)
                    i = i + 1;
                end
            end
            refreshCounter(app)

            if testForBounds(app) == false
                drawbox(app)
                updateColored(app)
                updateOriginal(app)
            end
        end

        % Button pushed function: YESButton
        function YESButtonPushed(app, event)
            global index;
            boundsReached = testForBounds(app);
            if boundsReached == false
                index = index + 1;
                updateFullView(app)
                drawbox(app)
                updateColored(app)
                updateOriginal(app)
            end
        end

        % Button pushed function: NOButton
        function NOButtonPushed(app, event)
            global index blobMeasurements;
            blobMeasurements(index) = [];
            refreshCounter(app);
            boundsReached = testForBounds(app);
            if boundsReached == false
                updateFullView(app)
                drawbox(app)
                updateColored(app)
                updateOriginal(app)
            end
        end

        % Button pushed function: AddOneButton
        function AddOneButtonPushed(app, event)
            global extras;
            extras = extras + 1;
            refreshCounter(app)
        end

        % Button pushed function: RemoveOneButton
        function RemoveOneButtonPushed(app, event)
            global extras;
            extras = extras - 1;
            refreshCounter(app)
        end

        % Button pushed function: SpecifyAreaButton
        function SpecifyAreaButtonPushed(app, event)
            % Set up figure handle visibility, run ginput, and return state
            fhv = app.UIFigure.HandleVisibility;        % Current status
            app.UIFigure.HandleVisibility = 'callback'; % Temp change (or, 'on')
            set(0, 'CurrentFigure', app.UIFigure)       % Make fig current

            [x,y,~] = ginput(2);                        % run ginput

            app.UIFigure.HandleVisibility = fhv;        % return original state

            updateDepth(app,x,y)
        end

        % Button pushed function: PlayVideoButton
        function PlayVideoButtonPushed(app, event)
            playVideo(app)
            updateOriginal(app)
        end

        % Callback function: not associated with a component
        function testButtonPushed(app, event)
            global blobMeasurements
            coords = extractfield(blobMeasurements,'Centroid');
            output(:,1) = coords(1:2:end);
            output(:,2) = coords(2:2:end);
            if exist('output.xlsx', 'file')==2
                delete('output.xlsx');
            end
            writematrix(output,'output.xlsx')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FullViewImage = uiaxes(app.UIFigure);
            app.FullViewImage.XTick = [];
            app.FullViewImage.XTickLabel = '';
            app.FullViewImage.YTick = [];
            app.FullViewImage.YTickLabel = '';
            app.SpecificViewColored = uiaxes(app.UIFigure);
            app.SpecificViewColored.XTick = [];
            app.SpecificViewColored.XTickLabel = '';
            app.SpecificViewColored.YTick = [];
            app.SpecificViewColored.YTickLabel = '';
            app.SpecificViewOriginal = uiaxes(app.UIFigure);
            app.SpecificViewOriginal.XTick = [];
            app.SpecificViewOriginal.XTickLabel = '';
            app.SpecificViewOriginal.YTick = [];
            app.SpecificViewOriginal.YTickLabel = '';
            app.Depth1 = uiaxes(app.UIFigure);
            app.Depth1.XTick = [];
            app.Depth1.YTick = [];
            app.Depth2 = uiaxes(app.UIFigure);
            app.Depth2.XTick = [];
            app.Depth2.YTick = [];
            app.Depth3 = uiaxes(app.UIFigure);
            app.Depth3.XTick = [];
            app.Depth3.YTick = [];
            app.Depth4 = uiaxes(app.UIFigure);
            app.Depth4.XTick = [];
            app.Depth4.YTick = [];
            app.Depth5 = uiaxes(app.UIFigure);
            app.Depth5.XTick = [];
            app.Depth5.YTick = [];
            app.Depth6 = uiaxes(app.UIFigure);
            app.Depth6.XTick = [];
            app.Depth6.YTick = [];
            app.Depth7 = uiaxes(app.UIFigure);
            app.Depth7.XTick = [];
            app.Depth7.YTick = [];
            app.Depth8 = uiaxes(app.UIFigure);
            app.Depth8.XTick = [];
            app.Depth8.YTick = [];
            app.Depth9 = uiaxes(app.UIFigure);
            app.Depth9.XTick = [];
            app.Depth9.YTick = [];
            app.Depth10 = uiaxes(app.UIFigure);
            app.Depth10.XTick = [];
            app.Depth10.YTick = [];
            app.Depth11 = uiaxes(app.UIFigure);
            app.Depth11.XTick = [];
            app.Depth11.YTick = [];
            app.Depth12 = uiaxes(app.UIFigure);
            app.Depth12.XTick = [];
            app.Depth12.YTick = [];
            app.Stackfilenametif12imagelimitLabel = uilabel(app.UIFigure);
            app.Stackfilenametif12imagelimitLabel.Text = 'Stack file name (tif, 12 image limit):';
            app.StackNameEditField = uieditfield(app.UIFigure, 'text');
            app.ProjectionFilenameEditFieldLabel = uilabel(app.UIFigure);
            app.ProjectionFilenameEditFieldLabel.Text = 'Projection File name:';
            app.ImagefilenameEditField = uieditfield(app.UIFigure, 'text');
            app.LOADButton = uibutton(app.UIFigure, 'push');
            app.LOADButton.ButtonPushedFcn = createCallbackFcn(app, @LOADButtonPushed, true);
            app.LOADButton.Text = 'LOAD';
            app.BrightnessThresholddefault50EditFieldLabel = uilabel(app.UIFigure);
            app.BrightnessThresholddefault50EditFieldLabel.Text = 'Brightness Threshold (default: 50)';
            app.BrightnessThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelAreaThresholddefault2EditFieldLabel = uilabel(app.UIFigure);
            app.PixelAreaThresholddefault2EditFieldLabel.Text = 'Pixel Area Threshold (default: 2)';
            app.PixelAreaThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpdateThresholdButton = uibutton(app.UIFigure, 'push');
            app.UpdateThresholdButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateThresholdButtonPushed, true);
            app.UpdateThresholdButton.Text = 'Update';
            app.CrossoverLabel = uilabel(app.UIFigure);
            app.CrossoverLabel.Text = 'Crossover?';
            app.YESButton = uibutton(app.UIFigure, 'push');
            app.YESButton.ButtonPushedFcn = createCallbackFcn(app, @YESButtonPushed, true);
            app.YESButton.Text = 'YES';
            app.NOButton = uibutton(app.UIFigure, 'push');
            app.NOButton.ButtonPushedFcn = createCallbackFcn(app, @NOButtonPushed, true);
            app.NOButton.Text = 'NO';
            app.AddOneButton = uibutton(app.UIFigure, 'push');
            app.AddOneButton.ButtonPushedFcn = createCallbackFcn(app, @AddOneButtonPushed, true);
            app.AddOneButton.Text = '+1';
            app.RemoveOneButton = uibutton(app.UIFigure, 'push');
            app.RemoveOneButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveOneButtonPushed, true);
            app.RemoveOneButton.Text = '-1';
            app.TotalcrossoversLabel = uilabel(app.UIFigure);
            app.TotalcrossoversLabel.Text = 'Total crossovers:';
            app.CrossoversCounter = uilabel(app.UIFigure);
            app.CrossoversCounter.Text = '0';
            app.SpecifyAreaButton = uibutton(app.UIFigure, 'push');
            app.SpecifyAreaButton.ButtonPushedFcn = createCallbackFcn(app, @SpecifyAreaButtonPushed, true);
            app.SpecifyAreaButton.Text = 'Specify Area';
            app.PlayVideoButton = uibutton(app.UIFigure, 'push');
            app.PlayVideoButton.ButtonPushedFcn = createCallbackFcn(app, @PlayVideoButtonPushed, true);
            app.PlayVideoButton.Text = 'Play Video';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = userInterface3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end